classdef demo_intro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Plot1Tab                matlab.ui.container.Tab
        UIAxesAngle             matlab.ui.control.UIAxes
        Plot2Tab                matlab.ui.container.Tab
        UIAxesTorque            matlab.ui.control.UIAxes
        AnimationTab            matlab.ui.container.Tab
        UIAxes                  matlab.ui.control.UIAxes
        RobotLinkDropDownLabel  matlab.ui.control.Label
        RobotLinkDropDown       matlab.ui.control.DropDown
        th1TEditFieldLabel      matlab.ui.control.Label
        th1TEditField           matlab.ui.control.NumericEditField
        th10EditFieldLabel      matlab.ui.control.Label
        th10EditField           matlab.ui.control.NumericEditField
        th2TEditFieldLabel      matlab.ui.control.Label
        th2TEditField           matlab.ui.control.NumericEditField
        th20EditFieldLabel      matlab.ui.control.Label
        th20EditField           matlab.ui.control.NumericEditField
        m1EditFieldLabel        matlab.ui.control.Label
        m1EditField             matlab.ui.control.NumericEditField
        m2EditFieldLabel        matlab.ui.control.Label
        m2EditField             matlab.ui.control.NumericEditField
        a1EditFieldLabel        matlab.ui.control.Label
        a1EditField             matlab.ui.control.NumericEditField
        a2EditFieldLabel        matlab.ui.control.Label
        a2EditField             matlab.ui.control.NumericEditField
        AnimateButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        %th1T, th10, th2T, th20, m1, m2, a1, a2 % Description
         th1T = pi; 
         th10 = 0; 
         th2T = pi/2; 
         th20 = 0;
         m1 = 2; 
         m2 = 2; 
         a1 = 1; 
         a2 = 1; 
         g = 9.81;
         value = '1-link';
         tspan = [0 10];
         y0 = [0; 0; 0; 0];
    end
    
    methods (Access = private)
        
        function [] = plot_val(app, value)
            if value == "1-link"
                [ti, th, thd, thdd, tau] = link1(app.th1T, app.th10, app.m1, app.a1);
                plot(app.UIAxesAngle, ti, th, '-', ti, thd, ':', ti, thdd, '-.');
                plot(app.UIAxesTorque, ti, tau)
                xlabel(app.UIAxesAngle, 'Time (sec)')
                ylabel(app.UIAxesAngle, 'Joint motion')
                xlabel(app.UIAxesTorque, 'Time (sec)')
                ylabel(app.UIAxesTorque, 'Joint Torque')
                
                
            elseif value == "2-link arm"
                [ti, th1, th2, tor1, tor2] = link2(app.th1T, app.th10, app.th2T, app.th20, app.m1, app.m2, app.a1, app.a2);
                plot (app.UIAxesAngle,ti, th1,'-',ti, th2,':')
                plot (app.UIAxesTorque, ti, tor1,'-',ti, tor2,':');
                xlabel(app.UIAxesAngle, 'Time (sec)')
                ylabel(app.UIAxesAngle, 'Joint angle')
                xlabel(app.UIAxesTorque, 'Time (sec)')
                ylabel(app.UIAxesTorque, 'Joint Torque')
            elseif value == "2-link manipulator"
                [t, y] = ode45('link2mani', app.tspan, app.y0);
                disp("calculated");

                plot (app.UIAxesAngle, t, y(:,1),'-',t, y(:,3),':');
                plot (app.UIAxesTorque, t, y(:,2),'-',t, y(:,4),':');
                xlabel(app.UIAxesAngle, 'Time (sec)')
                ylabel(app.UIAxesAngle, 'Joint angle (rad) and rate (rad/sec)')
               
                xlabel(app.UIAxesTorque, 'Time (sec)')
                ylabel(app.UIAxesTorque, 'Joint angle (rad) and rate (rad/sec)')
                
                
            end
            
        end
    end
    

    methods (Access = private)

        % Value changed function: RobotLinkDropDown
        function RobotLinkDropDownValueChanged(app, event)
            app.value = app.RobotLinkDropDown.Value;
            plot_val(app, app.value);
            
        end

        % Value changed function: th1TEditField
        function th1TEditFieldValueChanged(app, event)
            app.th1T = app.th1TEditField.Value;
            plot_val(app, app.value);
            
        end

        % Value changed function: th10EditField
        function th10EditFieldValueChanged(app, event)
            app.th10 = app.th10EditField.Value;
            plot_val(app, app.value);
            
        end

        % Value changed function: th2TEditField
        function th2TEditFieldValueChanged(app, event)
            app.th2T = app.th2TEditField.Value;
            plot_val(app, app.value);
        end

        % Value changed function: th20EditField
        function th20EditFieldValueChanged(app, event)
            app.th20 = app.th20EditField.Value;
            plot_val(app, app.value);
        end

        % Value changed function: m1EditField
        function m1EditFieldValueChanged(app, event)
            app.m1 = app.m1EditField.Value;
            plot_val(app, app.value);
            
        end

        % Value changed function: m2EditField
        function m2EditFieldValueChanged(app, event)
            app.m2 = app.m2EditField.Value;
            plot_val(app, app.value);
            
        end

        % Value changed function: a1EditField
        function a1EditFieldValueChanged(app, event)
            app.a1 = app.a1EditField.Value;
            plot_val(app, app.value);
        end

        % Value changed function: a2EditField
        function a2EditFieldValueChanged(app, event)
            app.a2 = app.a2EditField.Value;
            plot_val(app, app.value);
        end

        % Size changed function: Plot1Tab
        function Plot1TabSizeChanged(app, event)
            position = app.Plot1Tab.Position;
            
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            animate(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Plot1Tab = uitab(app.TabGroup);
            app.Plot1Tab.SizeChangedFcn = createCallbackFcn(app, @Plot1TabSizeChanged, true);
            app.Plot1Tab.Title = 'Plot 1';
            app.UIAxesAngle = uiaxes(app.Plot1Tab);
            title(app.UIAxesAngle, '')
            xlabel(app.UIAxesAngle, 'X')
            ylabel(app.UIAxesAngle, 'Y')
            app.Plot2Tab = uitab(app.TabGroup);
            app.Plot2Tab.Title = 'Plot 2';
            app.UIAxesTorque = uiaxes(app.Plot2Tab);
            title(app.UIAxesTorque, '')
            xlabel(app.UIAxesTorque, 'X')
            ylabel(app.UIAxesTorque, 'Y')
            app.AnimationTab = uitab(app.TabGroup);
            app.AnimationTab.Title = 'Animation';
            app.UIAxes = uiaxes(app.AnimationTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RobotLinkDropDownLabel = uilabel(app.UIFigure);
            app.RobotLinkDropDownLabel.Text = 'Robot Link';
            app.RobotLinkDropDown = uidropdown(app.UIFigure);
            app.RobotLinkDropDown.Items = {'Select', '1-link', '2-link arm', '2-link manipulator', ''};
            app.RobotLinkDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotLinkDropDownValueChanged, true);
            app.RobotLinkDropDown.Value = 'Select';
            app.th1TEditFieldLabel = uilabel(app.UIFigure);
            app.th1TEditFieldLabel.Text = 'th1T';
            app.th1TEditField = uieditfield(app.UIFigure, 'numeric');
            app.th1TEditField.ValueChangedFcn = createCallbackFcn(app, @th1TEditFieldValueChanged, true);
            app.th10EditFieldLabel = uilabel(app.UIFigure);
            app.th10EditFieldLabel.Text = 'th10';
            app.th10EditField = uieditfield(app.UIFigure, 'numeric');
            app.th10EditField.ValueChangedFcn = createCallbackFcn(app, @th10EditFieldValueChanged, true);
            app.th2TEditFieldLabel = uilabel(app.UIFigure);
            app.th2TEditFieldLabel.Text = 'th2T';
            app.th2TEditField = uieditfield(app.UIFigure, 'numeric');
            app.th2TEditField.ValueChangedFcn = createCallbackFcn(app, @th2TEditFieldValueChanged, true);
            app.th20EditFieldLabel = uilabel(app.UIFigure);
            app.th20EditFieldLabel.Text = 'th20';
            app.th20EditField = uieditfield(app.UIFigure, 'numeric');
            app.th20EditField.ValueChangedFcn = createCallbackFcn(app, @th20EditFieldValueChanged, true);
            app.m1EditFieldLabel = uilabel(app.UIFigure);
            app.m1EditFieldLabel.Text = 'm1';
            app.m1EditField = uieditfield(app.UIFigure, 'numeric');
            app.m1EditField.ValueChangedFcn = createCallbackFcn(app, @m1EditFieldValueChanged, true);
            app.m2EditFieldLabel = uilabel(app.UIFigure);
            app.m2EditFieldLabel.Text = 'm2';
            app.m2EditField = uieditfield(app.UIFigure, 'numeric');
            app.m2EditField.ValueChangedFcn = createCallbackFcn(app, @m2EditFieldValueChanged, true);
            app.a1EditFieldLabel = uilabel(app.UIFigure);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.UIFigure, 'numeric');
            app.a1EditField.ValueChangedFcn = createCallbackFcn(app, @a1EditFieldValueChanged, true);
            app.a2EditFieldLabel = uilabel(app.UIFigure);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.UIFigure, 'numeric');
            app.a2EditField.ValueChangedFcn = createCallbackFcn(app, @a2EditFieldValueChanged, true);
            app.AnimateButton = uibutton(app.UIFigure, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = demo_intro

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end